classdef convergence_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GeneratevisualizationsButton   matlab.ui.control.Button
        InputParametersPanel           matlab.ui.container.Panel
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        DistributiontypeDropDown       matlab.ui.control.DropDown
        DistributiontypeDropDownLabel  matlab.ui.control.Label
        MEditField                     matlab.ui.control.NumericEditField
        MEditFieldLabel                matlab.ui.control.Label
        ConvergenceVisualizerLabel     matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function [Y, mean_val] = generate_realizations(app, M, N, distribution_type)
            if distribution_type == "normal"
                distribution = makedist('Normal','mu',2,'sigma',sqrt(2));
                mean_val = 2;
            elseif distribution_type == "uniform" 
                distribution = makedist('Uniform', "Lower", 2, "Upper", 4);
                mean_val = 3;
            else
                distribution = makedist('Exponential','mu', 0.5);
                mean_val = 0.5;
            end
            Y = zeros(M, N);
            ax = app.UIAxes;
            ax.XTick = 1: N/5: N + 500;
            ax.YTick = -100: 1: 100;
            ax.XTickLabel = 1: N/5 :N + 500;
            ax.YTickLabel = -100: 1: 100;
            for i = 1: M
                X_i = distribution.random(1, N);
                Y(i, :) = transform(app, X_i);
                %Y(i, :) = transform_alt(app, distribution, N);
                plot(ax, 1: N, Y(i, :));
                hold(ax, "on");
            end
            hold(ax, "off");
        end
        
        function transformed_X = transform_alt(app, distribution, N)
            transformed_X = sum(distribution.random(N, N))/N;
        end
        
        function transformed_X = transform(app, X)
            length_X = length(X);
            transformed_X = zeros(1, length_X);
            summation = 0;
            for i = 1: 1: length_X
                summation = summation + X(i);
                transformed_X(i) = summation/i;
            end
        end

        function modes_of_convergence(app, Y, mean)
            [M, N] = size(Y);
            p_deviations = convergence_in_probability(app, Y, mean);
            [as_deviations, k] = almost_sure_convergence(app, Y, mean);
            ms_deviations = convergence_in_ms(app, Y, mean);
            ax = app.UIAxes2;
            ax.XTick = 1: N/5: N + 500;
            ax.YTick = -100: 1: 100;
            ax.XTickLabel = 1: N/5 :N + 500;
            ax.YTickLabel = -100: 1: 100;
            plot(ax, 1: N, p_deviations, 'DisplayName', "convergence in Probability");
            hold(ax, "on");
            plot(ax, 1: k*N, as_deviations, 'DisplayName', "Almost sure convergence");
            hold(ax, "on");
            plot(ax, 1: N, ms_deviations, 'DisplayName', "convergence in Mean Square");
            hold(ax, "off");
            legend(app.UIAxes2);
        end


        function deviations = convergence_in_probability(app, Y, mean)
            [M, N] = size(Y);
            e = 0.05;
            deviations = zeros(N, 1);
            for i = 1: N
                deviation_count = 0;
                for j = 1: M
                    if abs((Y(j, i) - mean)) > e
                        deviation_count = deviation_count + 1;
                    end
                end
                deviations(i) = deviation_count / M;
            end
        end

       
        function [deviations, k] = almost_sure_convergence(app, Y, mean)
            k = 0.5;
            [M, N] = size(Y);
            e = 0.05;
            deviations = zeros(k*N, 1);
            for i = 1: k*N
                deviation_count = 0;
                for j = 1: M
                    for l = i: N
                        if abs(Y(j, l) - mean) > e
                            deviation_count = deviation_count + 1;
                            break
                        end
                    end
                end
                deviations(i) = deviation_count / M;
            end
        end
    

        function deviations = convergence_in_ms(app, Y, mean)
            [M, N] = size(Y);
            deviations = zeros(1, N);
            for i = 1: N
                deviation_i = 0;
                for j = 1: M
                    deviation_i = deviation_i + ((Y(j, i) - mean)^2);
                end
                deviations(1, i) = deviation_i / M;
            end
        end

        
        function [cdf, t] = convergence_in_distribution(app, Y, mean)
            t = mean - 1: 0.00001: mean + 1;
            [M, N] = size(Y);
            cdf = zeros(1, length(t));
            for i = 1: length(t)
                cdf_i = 0;
                for j = 1: M
                    if Y(j, N) <= t(i)
                        cdf_i = cdf_i + 1;
                    end
                end
                cdf(1, i) = cdf_i / M;
            end
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratevisualizationsButton
        function GeneratevisualizationsButtonPushed(app, event)
            distribution_type = app.DistributiontypeDropDown.Value;
            N = app.NEditField.Value;
            M = app.MEditField.Value;
            [realizations, mean_val] = generate_realizations(app, M, N, distribution_type);
            modes_of_convergence(app, realizations, mean_val);
            [cdf_Y, t] = convergence_in_distribution(app, realizations, mean_val);
            ax = app.UIAxes3;
            ax.XTick = mean_val - 2: 0.25: mean_val + 2;
            ax.YTick = -100: 1: 100;
            ax.XTickLabel = mean_val - 2: 0.25: mean_val + 2;
            ax.YTickLabel = -100: 1: 100;
            plot(ax, t, cdf_Y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Realizations')
            xlabel(app.UIAxes, 'N: Number of samples per realization')
            ylabel(app.UIAxes, 'Y_M ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Convergences')
            xlabel(app.UIAxes2, 'N: Number of samples per realization')
            ylabel(app.UIAxes2, 'a_n  p_n  e_(n, r)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'CDF of Y')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'F_Y(t)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.ConvergenceVisualizerLabel = uilabel(app.UIFigure);
            app.ConvergenceVisualizerLabel.Text = '                                                                         Convergence Visualizer';
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = '                                                                                                                                 Input Parameters';
            app.MEditFieldLabel = uilabel(app.InputParametersPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.MEditField.Value = 500;
            app.DistributiontypeDropDownLabel = uilabel(app.InputParametersPanel);
            app.DistributiontypeDropDownLabel.Text = 'Distribution type';
            app.DistributiontypeDropDown = uidropdown(app.InputParametersPanel);
            app.DistributiontypeDropDown.Items = {'normal', 'uniform', 'exponential'};
            app.DistributiontypeDropDown.Value = 'normal';
            app.NEditFieldLabel = uilabel(app.InputParametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.NEditField.Value = 2000;
            app.GeneratevisualizationsButton = uibutton(app.UIFigure, 'push');
            app.GeneratevisualizationsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratevisualizationsButtonPushed, true);
            app.GeneratevisualizationsButton.Text = 'Generate visualizations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convergence_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end